classdef methodSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NumiiSolveUIFigure              matlab.ui.Figure
        NumiiSolveLabel                 matlab.ui.control.Label
        MathproblemIdoitforyouLabel     matlab.ui.control.Label
        ChooseyourtypeofproblemButtonGroup  matlab.ui.container.ButtonGroup
        NolinearequationButton_2        matlab.ui.control.ToggleButton
        NumericalintegrationButton_2    matlab.ui.control.ToggleButton
        NolinearequationsystemButton_2  matlab.ui.control.ToggleButton
        DiferentialequationsystemButton_2  matlab.ui.control.ToggleButton
        ListBox                         matlab.ui.control.ListBox
        ListBox_2                       matlab.ui.control.ListBox
        ListBox_3                       matlab.ui.control.ListBox
        ListBox_4                       matlab.ui.control.ListBox
        uiAxesError                     matlab.ui.control.UIAxes
        EcuationEditField               matlab.ui.control.EditField
        RangeofIntervalLabel            matlab.ui.control.Label
        MinEditField_2Label             matlab.ui.control.Label
        MinEditField_2                  matlab.ui.control.NumericEditField
        MaxEditField_2Label             matlab.ui.control.Label
        MaxEditField_2                  matlab.ui.control.NumericEditField
        IterationsLabel                 matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.NumericEditField
        EVALUALTEButton                 matlab.ui.control.Button
        RootLabel                       matlab.ui.control.Label
        Label_root                      matlab.ui.control.Label
        EaLabel                         matlab.ui.control.Label
        Label_ea                        matlab.ui.control.Label
        EcuationxLabel                  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        UITable2                        matlab.ui.control.Table
        XiSpinnerLabel                  matlab.ui.control.Label
        XiSpinner                       matlab.ui.control.Spinner
        InitialValueEditFieldLabel      matlab.ui.control.Label
        InitialValueEditField           matlab.ui.control.NumericEditField
        DerivativeLabel                 matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        AXLabel                         matlab.ui.control.Label
        BLabel                          matlab.ui.control.Label
        UITable3                        matlab.ui.control.Table
        uiAxesFunction                  matlab.ui.control.UIAxes
        MsgLabel                        matlab.ui.control.Label
        Label_msg                       matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function results = func(app)
            % Se hace desaparecer todo y los valores se reinician
            app.ListBox_4.Visible = false;
            app.ListBox_3.Visible = false;
            app.ListBox_2.Visible = false;
            app.ListBox.Visible = false;
            % Respuesta
            app.Label_root.Visible = false;
            app.Label_ea.Visible = false;
            app.Label_msg.Visible = false;
            app.RootLabel.Visible = false;
            app.EaLabel.Visible = false;
            app.MsgLabel.Visible = false;
            % Grafico
            app.uiAxesError.Position = [576 30 0 0];
            app.uiAxesFunction.Position = [22 30 0 0];
            %reset graphics data
            cla(app.uiAxesError);            
            cla(app.uiAxesFunction);
            %reset the title
            title(app.uiAxesFunction, 'Function');
            % Tablas
            app.UITable.Visible = false;
            app.UITable.Enable = 'on';
            app.UITable.Data = zeros(2);
            app.UITable2.Visible = false;
            app.UITable2.Enable = 'on';
            app.UITable2.Data = zeros(2,1);
            app.UITable3.Visible = false;
            app.AXLabel.Visible = false;
            app.BLabel.Visible = false;
            % x#
            app.XiSpinner.Visible = false;
            app.XiSpinner.Value = 2;
            app.XiSpinnerLabel.Visible = false;
            % Valor inicial
            app.InitialValueEditFieldLabel.Visible = false;
            app.InitialValueEditField.Visible = false;
            app.InitialValueEditField.Value = 0;
            % Ecuacion
            app.EcuationEditField.Visible = false;
            app.EcuationxLabel.Visible = false;
            app.EcuationEditField.Value = "";
            % Rango
            app.MaxEditField_2.Visible = false;
            app.MaxEditField_2Label.Visible = false;
            app.MaxEditField_2.Value = 0;
            app.MinEditField_2Label.Visible = false;
            app.MinEditField_2.Visible = false;
            app.MinEditField_2.Value = 0;
            app.RangeofIntervalLabel.Visible = false;
            % Iteraciones
            app.IterationsLabel.Visible = false;
            app.IterationsEditField.Visible = false;
            app.IterationsEditField.Value = 1;
            % Derivada
            app.DerivativeLabel.Visible = false;
            app.Label2.Visible = false;
        end
        
        %graphicate the function
        function results = graphicate(app,min,max,h)
            title(app.uiAxesFunction, h);
            handle = matlabFunction(evalin(symengine, h));
            Lmin = min;
            Lmax = max;
            escalar = 100;
            position = (Lmax-Lmin)/escalar;
            x = Lmin:position:Lmax;
            y = handle(x);
            plot(app.uiAxesFunction,x,y);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            func(app);
            % por default esta seleccionado Nolinearequation y Biseccion
            app.ListBox_2.Value = "Bisection";
            app.ListBox_2.Visible = true;
            
            % Ecuacion
            app.EcuationEditField.Visible = true;
            app.EcuationxLabel.Visible = true;
            
            % Rango
            app.MaxEditField_2.Visible = true;
            app.MaxEditField_2Label.Visible = true;
            app.MinEditField_2Label.Visible = true;
            app.MinEditField_2.Visible = true;
            app.RangeofIntervalLabel.Visible = true;
            
            % Iteraciones
            app.IterationsLabel.Visible = true;
            app.IterationsEditField.Visible = true;
            
            % Grafico
            app.uiAxesError.Visible = true;
            app.uiAxesFunction.Visible = true;
            app.uiAxesFunction.Position = [22 30 365 224];
            app.uiAxesError.Position = [576 30 365 224];
        end

        % Selection changed function: 
        % ChooseyourtypeofproblemButtonGroup
        function ChooseyourtypeofproblemButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseyourtypeofproblemButtonGroup.SelectedObject;
            % Desaparece todo
            func(app);
            % Aparece solo lo que se selecciona
            switch(selectedButton)
                case app.NolinearequationButton_2
                    % por default esta seleccionado Biseccion
                    app.ListBox_2.Visible=true;
                    app.ListBox_2.Value = "Bisection";
                    % objetos que necesita
                    % Ecuacion
                    app.EcuationEditField.Visible = true;
                    app.EcuationxLabel.Visible = true;
                    % Rango
                    app.MaxEditField_2.Visible = true;
                    app.MaxEditField_2Label.Visible = true;
                    app.MinEditField_2Label.Visible = true;
                    app.MinEditField_2.Visible = true;
                    app.RangeofIntervalLabel.Visible = true;
                    % Iteraciones
                    app.IterationsLabel.Visible = true;
                    app.IterationsEditField.Visible = true;
                    % Graphics
                    app.uiAxesError.Visible = true;
                    app.uiAxesFunction.Visible = true;
                    app.uiAxesFunction.Position = [22 30 365 224];
                    app.uiAxesError.Position = [576 30 365 224];
                case app.NumericalintegrationButton_2
                    app.ListBox_3.Visible=true;
                    % por default esta Trapezoidal multiple
                    app.ListBox_3.Value = "Trapezoidal multiple";
                    % falta codigo
                case app.NolinearequationsystemButton_2
                    app.ListBox.Visible=true;
                    % por default esta seleccionado Gauss-Seidel
                    app.ListBox.Value = "Gauss-Seidel";
                    % objetos que necesita
                    % Tablas
                    app.UITable.Visible = true;
                    app.UITable2.Visible = true;
                    app.AXLabel.Visible = true;
                    app.BLabel.Visible = true;
                    % Iteraciones
                    app.IterationsLabel.Visible = true;
                    app.IterationsEditField.Visible = true;
                    % x#
                    app.XiSpinner.Visible = true;
                    app.XiSpinnerLabel.Visible = true;
                case app.DiferentialequationsystemButton_2
                    app.ListBox_4.Visible=true;
                    % por default esta seleccionado Euler
                    app.ListBox_4.Value = "Euler";
                    % falta codigo
            end
        end

        % Value changed function: ListBox_4
        function ListBox_4ValueChanged(app, event)
            value = app.ListBox_4.Value;
            % se vuelve a eliminar todo
            func(app);
            % objetos que todas las funciones necesitan
            app.ListBox_4.Visible = true;
            % objetos de cada funcion
            if(value == "Euler")
                % falta codigo
            elseif(value == "Clasico Runge-kutta")
                % falta codigo
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            % se vuelve a eliminar todo
            func(app);
            % objetos que todas las funciones necesitan
            app.ListBox.Visible = true;
            % Tablas
            app.UITable.Visible = true;
            app.UITable2.Visible = true;
            app.AXLabel.Visible = true;
            app.BLabel.Visible = true;
            % Iteraciones
            app.IterationsLabel.Visible = true;
            app.IterationsEditField.Visible = true;
            % x#
            app.XiSpinner.Visible = true;
            app.XiSpinnerLabel.Visible = true;
            % objetos de cada funcion
            if(value == "Gauss-Seidel")
                %
            elseif(value == "Newton-Raphson")
                % falta codigo
            end
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            value = app.ListBox_2.Value;
            % se vuelve a eliminar todo
            func(app);
            % objetos que todas las funciones necesitan
            app.ListBox_2.Visible = true;
            % Ecuacion
            app.EcuationEditField.Visible = true;
            app.EcuationxLabel.Visible = true;
            % Iteraciones
            app.IterationsLabel.Visible = true;
            app.IterationsEditField.Visible = true;
            % Graphics
            app.uiAxesError.Visible = true;
            app.uiAxesFunction.Visible = true;
            app.uiAxesError.Position = [576 30 365 224];
            app.uiAxesFunction.Position = [22 30 365 224];
            % objetos de cada funcion
            if(value == "Bisection" || value == "Regular Falsi")
                % Rango
                app.MaxEditField_2.Visible = true;
                app.MaxEditField_2Label.Visible = true;
                app.MinEditField_2Label.Visible = true;
                app.MinEditField_2.Visible = true;
                app.RangeofIntervalLabel.Visible = true;
            elseif(value == "Simple Iteration fix point")
                % Valor inicial
                app.InitialValueEditFieldLabel.Visible = true;
                app.InitialValueEditField.Visible = true;
            elseif(value =="Newton- Raphson")
                % Valor inicial
                app.InitialValueEditFieldLabel.Visible = true;
                app.InitialValueEditField.Visible = true;
                % Derivada
                app.DerivativeLabel.Visible = true;
            end
        end

        % Value changed function: ListBox_3
        function ListBox_3ValueChanged(app, event)
            value = app.ListBox_3.Value;
            % se vuelve a eliminar todo
            func(app);
            % objetos que todas las funciones necesitan
            app.ListBox_3.Visible = true;
            % objetos de cada funcion
            if(value == "Trapezoidal multiple")
                % falta codigo
            elseif(value == "Simpson 3/8 multiple")
                % falta codigo
            end
        end

        % Button pushed function: EVALUALTEButton
        function EVALUALTEButtonPushed(app, event)
            
            % Se asume que los valores ingresados son validos
            switch(app.ChooseyourtypeofproblemButtonGroup.SelectedObject)
                case app.NolinearequationButton_2
                    switch(app.ListBox_2.Value)
                        case "Bisection"
                            %get the input values
                            handle = app.EcuationEditField.Value;
                            max = app.MaxEditField_2.Value;
                            min = app.MinEditField_2.Value;
                            ite = app.IterationsEditField.Value;
                            
                            %call the function
                            [root, ea, Ea, msg] = bisection(handle,min,max,ite);
                            
                            %add the Ea values to the plot
                            plot(app.uiAxesError,Ea);
                            
                            %graphicate the function
                            graphicate(app,min,max,handle);
                            
                            %appear the Ea, Root, Msg 
                            app.EaLabel.Visible = true;
                            app.RootLabel.Visible = true;
                            app.MsgLabel.Visible = true;
                            
                            app.Label_root.Visible = true;
                            app.Label_ea.Visible = true;
                            app.Label_msg.Visible = true;
                            
                            app.Label_root.Text = num2str(root,'%4.5f');
                            app.Label_ea.Text = num2str(ea,'%4.5f');
                            app.Label_msg.Text = msg;
                            
                        case "Regular Falsi"
                            
                        case "Simple Iteration fix point"
                            
                        case "Newton- Raphson"
                            
                    end
                case app.NumericalintegrationButton_2
                    switch(app.ListBox_3.Value)
                        case "Trapezoidal multiple"
                            
                        case "Simpson 3/8 multiple"
                            
                    end
                case app.NolinearequationsystemButton_2
                    switch(app.ListBox.Value)
                        case "Gauss-Seidel"
                            
                        case "Newton-Raphson"
                            
                    end
                case app.DiferentialequationsystemButton_2
                    
            end
        end

        % Value changed function: XiSpinner
        function XiSpinnerValueChanged(app, event)
            value = app.XiSpinner.Value;
            % cada vez que se cambia el valor, se actualizan las tablas
            app.UITable.Data = zeros(value);
            app.UITable2.Data = zeros(value,1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NumiiSolveUIFigure = uifigure;
            app.NumiiSolveUIFigure.Name = 'Numii Solve';
            app.NumiiSolveLabel = uilabel(app.NumiiSolveUIFigure);
            app.NumiiSolveLabel.Text = 'Numii Solve';
            app.MathproblemIdoitforyouLabel = uilabel(app.NumiiSolveUIFigure);
            app.MathproblemIdoitforyouLabel.Text = 'Math problem? I do it for you!';
            app.ChooseyourtypeofproblemButtonGroup = uibuttongroup(app.NumiiSolveUIFigure);
            app.ChooseyourtypeofproblemButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseyourtypeofproblemButtonGroupSelectionChanged, true);
            app.ChooseyourtypeofproblemButtonGroup.Title = 'Choose your type of problem:';
            app.NolinearequationButton_2 = uitogglebutton(app.ChooseyourtypeofproblemButtonGroup);
            app.NolinearequationButton_2.Text = 'No linear equation';
            app.NolinearequationButton_2.Value = true;
            app.NumericalintegrationButton_2 = uitogglebutton(app.ChooseyourtypeofproblemButtonGroup);
            app.NumericalintegrationButton_2.Text = 'Numerical integration';
            app.NolinearequationsystemButton_2 = uitogglebutton(app.ChooseyourtypeofproblemButtonGroup);
            app.NolinearequationsystemButton_2.Text = 'No linear equation system';
            app.DiferentialequationsystemButton_2 = uitogglebutton(app.ChooseyourtypeofproblemButtonGroup);
            app.DiferentialequationsystemButton_2.Text = 'Diferential equation system';
            app.ListBox = uilistbox(app.NumiiSolveUIFigure);
            app.ListBox.Items = {'Gauss-Seidel', 'Newton-Raphson'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Newton-Raphson';
            app.ListBox_2 = uilistbox(app.NumiiSolveUIFigure);
            app.ListBox_2.Items = {'Bisection', 'Regular Falsi', 'Simple Iteration fix point', 'Newton- Raphson'};
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = 'Bisection';
            app.ListBox_3 = uilistbox(app.NumiiSolveUIFigure);
            app.ListBox_3.Items = {'Trapezoidal multiple', 'Simpson 3/8 multiple'};
            app.ListBox_3.ValueChangedFcn = createCallbackFcn(app, @ListBox_3ValueChanged, true);
            app.ListBox_3.Value = 'Trapezoidal multiple';
            app.ListBox_4 = uilistbox(app.NumiiSolveUIFigure);
            app.ListBox_4.Items = {'Euler', 'Clasico Runge-kutta'};
            app.ListBox_4.ValueChangedFcn = createCallbackFcn(app, @ListBox_4ValueChanged, true);
            app.ListBox_4.Value = 'Euler';
            app.uiAxesError = uiaxes(app.NumiiSolveUIFigure);
            title(app.uiAxesError, 'Error')
            xlabel(app.uiAxesError, 'Iterations')
            ylabel(app.uiAxesError, 'Ea')
            app.EcuationEditField = uieditfield(app.NumiiSolveUIFigure, 'text');
            app.RangeofIntervalLabel = uilabel(app.NumiiSolveUIFigure);
            app.RangeofIntervalLabel.Text = 'Range of Interval';
            app.MinEditField_2Label = uilabel(app.NumiiSolveUIFigure);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_2 = uieditfield(app.NumiiSolveUIFigure, 'numeric');
            app.MaxEditField_2Label = uilabel(app.NumiiSolveUIFigure);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_2 = uieditfield(app.NumiiSolveUIFigure, 'numeric');
            app.IterationsLabel = uilabel(app.NumiiSolveUIFigure);
            app.IterationsLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.NumiiSolveUIFigure, 'numeric');
            app.IterationsEditField.Limits = [1 Inf];
            app.IterationsEditField.Value = 1;
            app.EVALUALTEButton = uibutton(app.NumiiSolveUIFigure, 'push');
            app.EVALUALTEButton.ButtonPushedFcn = createCallbackFcn(app, @EVALUALTEButtonPushed, true);
            app.EVALUALTEButton.Text = 'EVALUALTE';
            app.RootLabel = uilabel(app.NumiiSolveUIFigure);
            app.RootLabel.Text = 'Root';
            app.Label_root = uilabel(app.NumiiSolveUIFigure);
            app.Label_root.Text = '';
            app.EaLabel = uilabel(app.NumiiSolveUIFigure);
            app.EaLabel.Text = 'Ea';
            app.Label_ea = uilabel(app.NumiiSolveUIFigure);
            app.Label_ea.Text = '';
            app.EcuationxLabel = uilabel(app.NumiiSolveUIFigure);
            app.EcuationxLabel.Text = 'Ecuation @(x)';
            app.UITable = uitable(app.NumiiSolveUIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.NumiiSolveUIFigure);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.XiSpinnerLabel = uilabel(app.NumiiSolveUIFigure);
            app.XiSpinnerLabel.Text = 'Xi';
            app.XiSpinner = uispinner(app.NumiiSolveUIFigure);
            app.XiSpinner.Limits = [2 Inf];
            app.XiSpinner.RoundFractionalValues = 'on';
            app.XiSpinner.ValueDisplayFormat = '%11g';
            app.XiSpinner.ValueChangedFcn = createCallbackFcn(app, @XiSpinnerValueChanged, true);
            app.XiSpinner.Value = 2;
            app.InitialValueEditFieldLabel = uilabel(app.NumiiSolveUIFigure);
            app.InitialValueEditFieldLabel.Text = 'Initial Value';
            app.InitialValueEditField = uieditfield(app.NumiiSolveUIFigure, 'numeric');
            app.DerivativeLabel = uilabel(app.NumiiSolveUIFigure);
            app.DerivativeLabel.Text = 'Derivative';
            app.Label2 = uilabel(app.NumiiSolveUIFigure);
            app.Label2.Text = 'Label2';
            app.AXLabel = uilabel(app.NumiiSolveUIFigure);
            app.AXLabel.Text = 'AX#';
            app.BLabel = uilabel(app.NumiiSolveUIFigure);
            app.BLabel.Text = 'B';
            app.UITable3 = uitable(app.NumiiSolveUIFigure);
            app.UITable3.ColumnName = '';
            app.UITable3.RowName = {};
            app.uiAxesFunction = uiaxes(app.NumiiSolveUIFigure);
            title(app.uiAxesFunction, 'Function')
            xlabel(app.uiAxesFunction, 'x')
            ylabel(app.uiAxesFunction, 'f(x)')
            app.MsgLabel = uilabel(app.NumiiSolveUIFigure);
            app.MsgLabel.Text = 'Msg';
            app.Label_msg = uilabel(app.NumiiSolveUIFigure);
            app.Label_msg.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = methodSelector

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NumiiSolveUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NumiiSolveUIFigure)
        end
    end
end